classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        V3D                 matlab.ui.control.CheckBox
        Paso                matlab.ui.control.NumericEditField
        PasoEditFieldLabel  matlab.ui.control.Label
        Simular             matlab.ui.control.Button
        Ejes                matlab.ui.control.Slider
        EjesSliderLabel     matlab.ui.control.Label
        Grafico             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Ejes
        function EjesValueChanged(app, event)
            value = app.Ejes.Value;
            
        end

        % Callback function
        function PasoValueChanged(app, event)
            value = app.Paso.Value;
            
        end

        % Button pushed function: Simular
        function SimularButtonPushed(app, event)
            cla(app.Grafico, "reset"); %función para limpiar las variables del grafico
            vpaso = app.Paso.Value; %valor del tamaño de paso
            vejes = app.Ejes.Value; %valor para determinar el tamaño de los ejes
            v3d = app.V3D.Value; %graficar en 3D
            
            %se generan los puntos del grid
            [x,y]= meshgrid(-vejes:vpaso:vejes); %Se pone el valor del -ejes, paso y ejes
            z = sin(x).*x;

            %se crea un condicional para graficar en 3d, si el check box
            %esta seleccionado, se grafica en 3D, si no, solo en 2D

            if v3d == 0
                plot(app.Grafico,x,y,'. r');
            else
                plot3(app.Grafico, x,y,z, '. b');
            end
        end

        % Value changed function: V3D
        function V3DValueChanged(app, event)
            value = app.V3D.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafico = uiaxes(app.UIFigure);
            title(app.Grafico, 'Title')
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            zlabel(app.Grafico, 'Z')
            app.EjesSliderLabel = uilabel(app.UIFigure);
            app.EjesSliderLabel.Text = 'Ejes';
            app.Ejes = uislider(app.UIFigure);
            app.Ejes.Limits = [3 8];
            app.Ejes.ValueChangedFcn = createCallbackFcn(app, @EjesValueChanged, true);
            app.Ejes.Value = 5;
            app.Simular = uibutton(app.UIFigure, 'push');
            app.Simular.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.Simular.Text = 'Simular';
            app.PasoEditFieldLabel = uilabel(app.UIFigure);
            app.PasoEditFieldLabel.Text = 'Paso';
            app.Paso = uieditfield(app.UIFigure, 'numeric');
            app.Paso.Limits = [0.2 0.8];
            app.Paso.Value = 0.4;
            app.V3D = uicheckbox(app.UIFigure);
            app.V3D.ValueChangedFcn = createCallbackFcn(app, @V3DValueChanged, true);
            app.V3D.Text = '3D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end