classdef grfico_ejemplo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EspacioPuntos             matlab.ui.control.NumericEditField
        EspacioentrepuntosEditFieldLabel  matlab.ui.control.Label
        EjesSlider                matlab.ui.control.Slider
        EjesSliderLabel           matlab.ui.control.Label
        TipodegraficaButtonGroup  matlab.ui.container.ButtonGroup
        Mesh                      matlab.ui.control.RadioButton
        Surf                      matlab.ui.control.RadioButton
        Plot3                     matlab.ui.control.RadioButton
        Graficar                  matlab.ui.control.Button
        Grafico                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EjesSlider
        function EjesSliderValueChanged(app, event)
            value = app.EjesSlider.Value;
            
        end

        % Button pushed function: Graficar
        function GraficarButtonPushed(app, event)
            cla(app.Grafico,"reset");
            vejes = app.EjesSlider.Value;
            vpaso = app.EspacioPuntos.Value;

            opmesh = app.Mesh.Value;
            opsurf = app.Surf.Value;
            opplot3 = app.Plot3.Value;

            [x,y]= meshgrid(-vejes:vpaso:vejes);
            r = sqrt(x.^2+y.^2)+eps;
            z = sin(r)./r;

            if opmesh == 1
                mesh(app.Grafico,x,y,z)
            elseif opsurf ==1
                surf(app.Grafico,x,y,z)
            elseif opplot3==1
                plot3(app.Grafico,x,y,z)
            end
        

        end

        % Value changed function: EspacioPuntos
        function EspacioPuntosValueChanged(app, event)
            value = app.EspacioPuntos.Value;
            
        end

        % Selection changed function: TipodegraficaButtonGroup
        function TipodegraficaButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipodegraficaButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafico = uiaxes(app.UIFigure);
            title(app.Grafico, 'Ejemplo de gráfico')
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            zlabel(app.Grafico, 'Z')
            app.Graficar = uibutton(app.UIFigure, 'push');
            app.Graficar.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.Graficar.Text = 'Graficar';
            app.TipodegraficaButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodegraficaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodegraficaButtonGroupSelectionChanged, true);
            app.TipodegraficaButtonGroup.Title = 'Tipo de grafica';
            app.Plot3 = uiradiobutton(app.TipodegraficaButtonGroup);
            app.Plot3.Text = 'Plot3';
            app.Plot3.Value = true;
            app.Surf = uiradiobutton(app.TipodegraficaButtonGroup);
            app.Surf.Text = 'Surf';
            app.Mesh = uiradiobutton(app.TipodegraficaButtonGroup);
            app.Mesh.Text = 'Mesh';
            app.EjesSliderLabel = uilabel(app.UIFigure);
            app.EjesSliderLabel.Text = 'Ejes';
            app.EjesSlider = uislider(app.UIFigure);
            app.EjesSlider.Limits = [1 20];
            app.EjesSlider.ValueChangedFcn = createCallbackFcn(app, @EjesSliderValueChanged, true);
            app.EjesSlider.Value = 1;
            app.EspacioentrepuntosEditFieldLabel = uilabel(app.UIFigure);
            app.EspacioentrepuntosEditFieldLabel.Text = 'Espacio entre puntos';
            app.EspacioPuntos = uieditfield(app.UIFigure, 'numeric');
            app.EspacioPuntos.Limits = [0.3 0.8];
            app.EspacioPuntos.ValueChangedFcn = createCallbackFcn(app, @EspacioPuntosValueChanged, true);
            app.EspacioPuntos.Value = 0.3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grfico_ejemplo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end